classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IFRLapTimeSimulatorUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        EventDropDown                   matlab.ui.control.DropDown
        EventDropDownLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UITable2                        matlab.ui.control.Table
        RestoreDefaultsButton           matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        InitiateSimButton               matlab.ui.control.Button
        TypeofCarDropDown               matlab.ui.control.DropDown
        TypeofCarDropDownLabel          matlab.ui.control.Label
        LapTimeSimulatorSetupLabel      matlab.ui.control.Label
        ImperialFormulaRacingLabel      matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        VisualisationTab                matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        SelectOptionDropDown            matlab.ui.control.DropDown
        SelectOptionDropDownLabel       matlab.ui.control.Label
        SelectVariableDropDown_2        matlab.ui.control.DropDown
        SelectVariableDropDown_2Label   matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        Sweep1DTab                      matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        CurrentValueEditField           matlab.ui.control.NumericEditField
        CurrentValueEditFieldLabel      matlab.ui.control.Label
        NumberofPointsEditField_2       matlab.ui.control.NumericEditField
        NumberofPointsEditField_2Label  matlab.ui.control.Label
        RunSweepButton                  matlab.ui.control.Button
        UpperLimitEditField             matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        SelectVariableDropDown          matlab.ui.control.DropDown
        SelectVariableDropDownLabel     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Sweep2DTab                      matlab.ui.container.Tab
        Image_4                         matlab.ui.control.Image
        Run2DSweepButton                matlab.ui.control.Button
        NumberofPointsEditField_3       matlab.ui.control.NumericEditField
        NumberofPointsEditField_3Label  matlab.ui.control.Label
        LowerLimitEditField_3Label      matlab.ui.control.Label
        LowerLimitEditField_3           matlab.ui.control.NumericEditField
        UpperLimitEditField_3Label      matlab.ui.control.Label
        UpperLimitEditField_3           matlab.ui.control.NumericEditField
        ValueEditField_2                matlab.ui.control.NumericEditField
        ValueEditField_2Label           matlab.ui.control.Label
        LowerLimitEditField_2Label      matlab.ui.control.Label
        LowerLimitEditField_2           matlab.ui.control.NumericEditField
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        UpperLimitEditField_2Label      matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
        Variable2DropDown               matlab.ui.control.DropDown
        Variable2DropDownLabel          matlab.ui.control.Label
        Variable1DropDown               matlab.ui.control.DropDown
        Variable1DropDownLabel          matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        PostProcessingTab               matlab.ui.container.Tab
        UITable3                        matlab.ui.control.Table
        Label_3                         matlab.ui.control.Label
        DatatodisplayDropDown           matlab.ui.control.DropDown
        DatatodisplayDropDownLabel      matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        AboutLabel                      matlab.ui.control.Label
    end

     
    properties (Access = private)

        car
        carprops % Description
        carvals
        sim
        npoints
        event
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            addpath('Coordinates\')
            addpath('GUI data\')
            addpath("Modules\")
            addpath("Objects\")
            addpath("Tires\")
            addpath('PProcessing\')
%commitasssist

             %Table Populator


            app.car = carFs;

            app.npoints = 2000;

            app.event = 1;
            app.EventDropDown.Value = "Sprint";


            % Read the CSV file into a table
            data = readCSV();
            app.SelectVariableDropDown.Items = {data.Var1{:}}';
            app.Variable1DropDown.Items = {data.Var1{:}}';
            app.Variable2DropDown.Items = {data.Var1{:}}';
            t = app.UITable;

            load tabledata.mat tabledata;

            set(t,'Data', data);

             app.CurrentValueEditField.Value = app.car.wheel_rad;

             app.UpperLimitEditField.Value = 1.25*app.car.wheel_rad;

             app.LowerLimitEditField.Value = 0.75*app.car.wheel_rad;

             app.NumberofPointsEditField_2.Value = 5;

             app.NumberofPointsEditField_3.Value = 5;
            

             app.ValueEditField.Value = app.car.wheel_rad;

             app.UpperLimitEditField_2.Value = 1.25*app.car.wheel_rad;

             app.LowerLimitEditField_2.Value = 0.75*app.car.wheel_rad;

             app.ValueEditField_2.Value = app.car.wheel_rad;

             app.UpperLimitEditField_3.Value = 1.25*app.car.wheel_rad;

             app.LowerLimitEditField_3.Value = 0.75*app.car.wheel_rad;


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            indx = indices(1);


            if ~isnumeric(newData) || ~isscalar(newData) || isnan(newData)

               warndlg('Please give a numeric value','Warning');

                t= app.UITable;

                t.Data{indx,2} = event.PreviousData;
                
               return
               

            end
            data = readCSV();
            if data.Var1{indx} == "CLA" || data.Var1{indx} == "CDA"
                 s = size(app.car.(data.Var1{indx}),3);
                t = size(app.car.(data.Var1{indx}),2);
                app.car.(data.Var1{indx})(end+1,:,:)  =newData.*ones(1,t,s);
            elseif data.Var1{indx} == "trackF" || data.Var1{indx} == "trackR"
                app.car.(data.Var1{indx}) = newData;
                app.car.track=(app.car.trackF + app.car.trackR) / 2;
            elseif data.Var1{indx} == "motor_ratio" 
                app.car.(data.Var1{indx}) = newData;
                app.car.ratios = newData;
            else
                app.car.(data.Var1{indx}) = newData;
            end

            % if indx == 1
            % 
            %     app.car.wheel_rad = newData;
            % 
            % elseif indx == 2
            % 
            %     app.car.mass = newData;
            % 
            % 
            % elseif indx == 3
            % 
            %     app.car.cog_height = newData;
            % 
            % elseif indx == 4
            % 
            %     app.car.cog_split = newData;
            % 
            % elseif indx == 5
            % 
            %     app.car.cog_LR_split = newData;
            % 
            % elseif indx == 6
            % 
            %     app.car.cop_height = newData;
            % 
            % elseif indx == 7
            % 
            %     app.car.cop_split = newData;
            % 
            % elseif indx == 8
            % 
            %     app.car.cop_LR_split = newData;
            % 
            % 
            % elseif indx == 9
            % 
            %     app.car.wheelbase = newData;
            % 
            % elseif indx == 10
            % 
            %     app.car.trackF = newData;
            % 
            % elseif indx == 11
            % 
            %     app.car.trackR = newData;  
            % 
            % elseif indx == 12
            %     s = size(app.car.CLA,3);
            %     t = size(app.car.CLA,2);
            %     app.car.CLA(end+1,:,:)  =newData.*ones(1,t,s);
            % 
            % elseif indx == 13
            % 
            %     s = size(app.car.CDA,3);
            %     t = size(app.car.CDA,2);
            %     app.car.CDA(end+1,:,:)  =newData.*ones(1,t,s);
            % 
            % 
            % end


        end

        % Button pushed function: InitiateSimButton
        function InitiateSimButtonPushed(app, event)
            fig = uifigure;

            d = uiprogressdlg(fig,'Title','Lap Time Simulator','Indeterminate','on','Message','Sim is running');            

            app.sim = runsim(app.car,app.npoints,1,app.event);

            close(d)
            close(fig)

            app.sim.x(end+1) = NaN;
            app.sim.y(end+1) = NaN;
            app.sim.v(end+1) = NaN;

            cla(app.UIAxes);
            p = patch(app.UIAxes,app.sim.x,app.sim.y,app.sim.v, EdgeColor = 'interp');
            p.LineWidth = 6;
            colorbar(app.UIAxes);
            colormap(app.UIAxes,"hot");
            % Add data table
            app.UITable2.Data{1,1} = 'Time';

            app.UITable2.Data{1,2} = app.sim.time(app.npoints);
            if app.event == 3
                start1 = find(app.sim.x>23.8258);
                start1 = start1(1);
                end1 = find(app.sim.x<25.199);
                end1 = end1(end);
                app.UITable2.Data{1,2} =  (app.sim.time(end1)-app.sim.time(start1))/4;


            end


        end

        % Value changed function: NumberofPointsEditField
        function NumberofPointsEditFieldValueChanged(app, event)

            value = double(app.NumberofPointsEditField.Value);

            app.npoints = value;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            labels = {'Save simulation object to workspace'};

            vars = {'Sim'};

            values = {app.sim};

            export2wsdlg(labels,vars,values);

        end

        % Value changed function: SelectVariableDropDown
        function SelectVariableDropDownValueChanged(app, event)
            value = app.SelectVariableDropDown.Value;
            
            if value=="CLA"||value=="CDA"
                app.CurrentValueEditField.Value = app.car.(value)(end,end,end);
            else


                app.CurrentValueEditField.Value = app.car.(value);      
            end
            % 
            % elseif isequal(value,'Mass')
            % 
            %     app.CurrentValueEditField.Value = app.car.(value)(end,end,end);
            % 
            % 
            % elseif isequal(value,'COG Height')
            % 
            %     app.CurrentValueEditField.Value = app.car.cog_height;
            % 
            % 
            % elseif isequal(value,'COG Split')
            % 
            %     app.CurrentValueEditField.Value = app.car.cog_split;
            % 
            % 
            % elseif isequal(value,'COG LR Split')
            % 
            %     app.CurrentValueEditField.Value = app.car.cog_LR_split;
            % 
            % 
            % elseif isequal(value,'COP Height')
            % 
            %     app.CurrentValueEditField.Value = app.car.cop_height;
            % 
            % 
            % elseif isequal(value,'COP Split')
            % 
            %     app.CurrentValueEditField.Value = app.car.cop_split;
            % 
            % 
            % elseif isequal(value,'COP LR Split')
            % 
            %     app.CurrentValueEditField.Value = app.car.cop_LR_split;
            % 
            % 
            % elseif isequal(value,'Wheelbase')
            % 
            %     app.CurrentValueEditField.Value = app.car.wheelbase;
            % 
            % 
            % elseif isequal(value,'TrackF')
            % 
            %     app.CurrentValueEditField.Value = app.car.trackF;
            % 
            % 
            % elseif isequal(value,'TrackR')
            % 
            %     app.CurrentValueEditField.Value = app.car.trackR;
            % 
            % 
            % elseif isequal(value,'CLA')
            % 
            %     app.CurrentValueEditField.Value = app.car.CLA(end,end,end);
            % 
            % 
            % elseif isequal(value,'CDA')
            % 
            %     app.CurrentValueEditField.Value = app.car.CDA(end,end,end);
            % 
            % elseif isequal(value,'Tyre Pressure')
            % 
            %     app.CurrentValueEditField.Value = app.car.tyre_pressure;
            % 
            % elseif isequal(value,'Motor Ratio')
            % 
            %     app.CurrentValueEditField.Value = app.car.motor_ratio;
            % 
            % elseif isequal(value,'Toe')
            % 
            %     app.CurrentValueEditField.Value = app.car.toe;
            % end

                app.UpperLimitEditField.Value = 1.25*app.CurrentValueEditField.Value;

                app.LowerLimitEditField.Value = 0.75*app.CurrentValueEditField.Value;

        end

        % Button pushed function: RunSweepButton
        function RunSweepButtonPushed(app, event)
            time = zeros(app.NumberofPointsEditField_2.Value,1);

            vals = linspace(app.LowerLimitEditField.Value,app.UpperLimitEditField.Value,app.NumberofPointsEditField_2.Value);

            its = app.NumberofPointsEditField_2.Value;

            fig = uifigure;

            d = uiprogressdlg(fig,'Title','Lap Time Simulator','Indeterminate','on','Message','Sweep is running');   

            typ = app.TypeofCarDropDown.Value;

            opt1 = app.SelectVariableDropDown.Value;

            nop = app.NumberofPointsEditField.Value;

            ev = app.event;

            parfor i = 1:its

                if typ == "RWD"

                    carsweep = carFs;

                else

                    carsweep = carFsAWD;


                end


                if opt1 == "CLA" || opt1 == "CDA"
                    s = size(carsweep.(opt1),3);
                    t = size(carsweep.(opt1),2);
                    carsweep.(opt1)(end+1,:,:)  =vals(i).*ones(1,t,s);
                elseif opt1 == "trackF" || opt1 == "trackR"
                    carsweep.(opt1) = vals(i);
                    carsweep.track=(carsweep.trackF + carsweep.trackR) / 2;
                else
                    carsweep.(opt1) = vals(i);
                end

                % if isequal(opt1,'Wheel Radius')
                % 
                %     carsweep.wheel_rad = vals(i);
                % 
                % elseif isequal(opt1,'Mass')
                % 
                %     carsweep.mass = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COG Height')
                % 
                %     carsweep.cog_height = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COG Split')
                % 
                %     carsweep.cog_split = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COG LR Split')
                % 
                %     carsweep.cog_LR_split = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COP Height')
                % 
                %     carsweep.cop_height = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COP Split')
                % 
                %    carsweep.cop_split = vals(i);
                % 
                % 
                % elseif isequal(opt1,'COP LR Split')
                % 
                %     carsweep.cop_LR_split = vals(i);
                % 
                % 
                % elseif isequal(opt1,'Wheelbase')
                % 
                %     carsweep.wheelbase = vals(i);
                % 
                % 
                % elseif isequal(opt1,'TrackF')
                % 
                %     carsweep.trackF = vals(i);
                % 
                % 
                % elseif isequal(opt1,'TrackR')
                % 
                %    carsweep.trackR = vals(i);
                % 
                % 
                % elseif isequal(opt1,'CLA')
                % 
                %     s = size(carsweep.CLA,3);
                %         t = size(carsweep.CLA,2);
                %         disp(carsweep.CLA(:,:,1))
                %         carsweep.CLA(end+1,:,:)  =vals(i).*ones(1,t,s);
                % 
                % 
                % elseif isequal(opt1,'CDA')
                % 
                %     s = size(carsweep.CDA,3);
                %         t = size(carsweep.CDA,2);
                %         carsweep.CDA(end+1,:,:)  =vals(i).*ones(1,t,s);
                % 
                % elseif isequal(opt1,'Tyre Pressure')
                % 
                %     carsweep.tyre_pressure = vals(i);
                % 
                % elseif isequal(opt1,'Motor Ratio')
                % 
                %     carsweep.motor_ratio = vals(i);
                %     carsweep.ratios = vals(i);
                % 
                % elseif isequal(opt1,'Toe')
                % 
                %     carsweep.toe = vals(i);
                % 
                % else
                % 
                %     continue
                % 
                % end
               
                tempsim = runsim(carsweep,nop,0,ev);
                time(i) = tempsim.time(nop);

            end

            close(d)
            close(fig)

            plot(app.UIAxes2,vals,time);
        end

        % Button pushed function: RestoreDefaultsButton
        function RestoreDefaultsButtonPushed(app, event)

            
            app.car = carFs;

            app.npoints = 2000;

            app.event = 1;
            app.EventDropDown.Value = "Sprint";


            % Read the CSV file into a table
            data = readCSV();
            app.SelectVariableDropDown.Items = {data.Var1{:}}';
            app.Variable1DropDown.Items = {data.Var1{:}}';
            app.Variable2DropDown.Items = {data.Var1{:}}';
            t = app.UITable;

            load tabledata.mat tabledata;

            set(t,'Data', data);
        end

        % Value changed function: SelectVariableDropDown_2
        function SelectVariableDropDown_2ValueChanged(app, event)

                 if isequal(app.SelectVariableDropDown_2.Value,'Ax')

                     app.SelectOptionDropDown.Items = {'Whole Car'};

                    cla(app.UIAxes3,'reset');

                    Ax = app.sim.slog.ax_log';

                    Ax(end+1) = NaN;

                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Ax, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    c = colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");


                elseif isequal(app.SelectVariableDropDown_2.Value,'Ay')

                    app.SelectOptionDropDown.Items = {'Whole Car'};
                    
                    cla(app.UIAxes3,'reset');

                    Ay = app.sim.slog.ay;

                    Ay(end+1) = NaN;
                    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Ay, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    c = colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");


                elseif isequal(app.SelectVariableDropDown_2.Value,'Fz')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    Fz = app.sim.slog.Fz(:,1,1);

                    Fz(end+1) = NaN;
                  
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fz, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    c = colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                

                elseif isequal(app.SelectVariableDropDown_2.Value,'Fy')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    Fy = app.sim.slog.Fy(:,1,1);

                    Fy(end+1) = NaN;

                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fy, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    c = colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                

                elseif isequal(app.SelectVariableDropDown_2.Value,'Fx')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    Fx = app.sim.slog.Fx(:,1,1);

                    Fx(end+1) = NaN;
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Tire Fx')

                     app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');


                    TireFx = app.sim.slog.Tire_Fx(:,1,1);

                    TireFx(end+1) = NaN;
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,TireFx, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Motor Fx')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    MotorFx = app.sim.slog.Motor_Fx(:,1,1);

                    MotorFx(end+1) = NaN;
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,MotorFx, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Lift')

                    app.SelectOptionDropDown.Items = {'Whole Car'};
                    cla(app.UIAxes3,'reset');

                    Fl = app.sim.slog.F_L;

                    Fl(end+1) = NaN;                    
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fl, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Drag')

                    app.SelectOptionDropDown.Items = {'Whole Car'};
                    cla(app.UIAxes3,'reset');

                    Fd = app.sim.slog.F_D;

                    Fd(end+1) = NaN;    

                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fd, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");
                                        
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'U')

                    app.SelectOptionDropDown.Items = {'Roll','Pitch','Ground Clearence'};

                    cla(app.UIAxes3,'reset');

                    U = app.sim.slog.U(:,1);

                    U(end+1) = NaN;    
   
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,U, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Slip Ratio')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    Sl = app.sim.slog.sl(:,1,1);

                    Sl(end+1) = NaN;    
                          
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Sl, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Slip Angle')

                    app.SelectOptionDropDown.Items = {'FL','FR','RL','RR'};

                    cla(app.UIAxes3,'reset');

                    Sa = app.sim.slog.sa(:,1,1);

                    Sa(end+1) = NaN;    

                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Sa, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");   
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Steering Angle')

                    app.SelectOptionDropDown.Items = {'Whole Car'};

                    cla(app.UIAxes3,'reset');                    

                    Sta = app.sim.slog.stra;

                    Sta(end+1) = NaN;    
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Sta, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot");   
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Chassis Angle')

                    app.SelectOptionDropDown.Items = {'Whole Car'};

                    cla(app.UIAxes3,'reset');

                    Ca = app.sim.slog.chang;

                    Ca(end+1) = NaN;   
    
                    p = patch(app.UIAxes3,app.sim.x,app.sim.y,Ca, EdgeColor = 'interp');
                    p.LineWidth = 6;
                    c = colorbar(app.UIAxes3);
                    colormap(app.UIAxes3,"hot"); 


                end


        end

        % Value changed function: SelectOptionDropDown
        function SelectOptionDropDownValueChanged(app, event)
            value = app.SelectOptionDropDown.Value;


               if isequal(app.SelectVariableDropDown_2.Value,'Ax')

                    return


                elseif isequal(app.SelectVariableDropDown_2.Value,'Ay')

                    return

                elseif isequal(app.SelectVariableDropDown_2.Value,'Fz')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fz = app.sim.slog.Fz(:,1,1);
    
                        Fz(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fz, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fz = app.sim.slog.Fz(:,1,2);
    
                        Fz(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fz, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fz = app.sim.slog.Fz(:,2,2);
    
                        Fz(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fz, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fz = app.sim.slog.Fz(:,2,1);
    
                        Fz(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fz, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                elseif isequal(app.SelectVariableDropDown_2.Value,'Fy')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fy = app.sim.slog.Fy(:,1,1);
    
                        Fy(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fy, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')

                        cla(app.UIAxes3,'reset');
    
                        Fy = app.sim.slog.Fy(:,1,2);
    
                        Fy(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fy, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fy = app.sim.slog.Fy(:,2,2);
    
                        Fy(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fy, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fy = app.sim.slog.Fy(:,2,1);
    
                        Fy(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fy, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                

                elseif isequal(app.SelectVariableDropDown_2.Value,'Fx')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Fx(:,1,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Fx(:,1,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Fx(:,2,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Fx(:,2,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Tire Fx')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Tire_Fx(:,1,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Tire_Fx(:,1,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Tire_Fx(:,2,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Tire_Fx(:,2,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Motor Fx')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Motor_Fx(:,1,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Motor_Fx(:,1,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Motor_Fx(:,2,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.Motor_Fx(:,2,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                    
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Lift')

                    return
                        
                elseif isequal(app.SelectVariableDropDown_2.Value,'Drag')

                    return
                                            
                elseif isequal(app.SelectVariableDropDown_2.Value,'U')


                    if isequal(value,'Roll')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.U(:,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'Pitch')

                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.U(:,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'Ground Clearence')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.U(:,3);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                  
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Slip Ratio')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sl(:,1,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sl(:,1,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sl(:,2,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sl(:,2,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end                  
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Slip Angle')

                    if isequal(value,'FL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sa(:,1,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'FR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sa(:,1,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end
                
                    if isequal(value,'RR')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sa(:,2,2);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end

                    if isequal(value,'RL')
                        cla(app.UIAxes3,'reset');
    
                        Fx = app.sim.slog.sa(:,2,1);
    
                        Fx(end+1) = NaN;
                      
                        p = patch(app.UIAxes3,app.sim.x,app.sim.y,Fx, EdgeColor = 'interp');
                        p.LineWidth = 6;
                        c = colorbar(app.UIAxes3);
                        colormap(app.UIAxes3,"hot");

                    end                  


                elseif isequal(app.SelectVariableDropDown_2.Value,'Steering Angle')

                        return  
    
                elseif isequal(app.SelectVariableDropDown_2.Value,'Chassis Angle')

                       return                  

                end
    
            
        end

        % Value changed function: Variable2DropDown
        function Variable2DropDownValueChanged(app, event)
            value = app.Variable2DropDown.Value;
            
            if value=="CLA"||value=="CDA"
                app.ValueEditField_2.Value = app.car.(value)(end,end,end);
            else


                app.ValueEditField_2.Value = app.car.(value);      
            end

                app.UpperLimitEditField_3.Value = 1.25*app.ValueEditField_2.Value;

                app.LowerLimitEditField_3.Value = 0.75*app.ValueEditField_2.Value;
        end

        % Button pushed function: Run2DSweepButton
        function Run2DSweepButtonPushed(app, event)

            if isequal(app.Variable1DropDown.Value,app.Variable2DropDown.Value)

                warndlg('Please select two different variables','Warning');

                return

            end

            time = zeros(app.NumberofPointsEditField_3.Value,app.NumberofPointsEditField_3.Value);

            vals1 = linspace(app.LowerLimitEditField_2.Value,app.UpperLimitEditField_2.Value,app.NumberofPointsEditField_3.Value);

            vals2 = linspace(app.LowerLimitEditField_3.Value,app.UpperLimitEditField_3.Value,app.NumberofPointsEditField_3.Value);

            its = app.NumberofPointsEditField_3.Value;

            fig = uifigure;

            d = uiprogressdlg(fig,'Title','Lap Time Simulator','Indeterminate','on','Message','2D Sweep is running');

            typ = app.TypeofCarDropDown.Value;

            opt1 = app.Variable1DropDown.Value;

            opt2 = app.Variable2DropDown.Value;

            nop = app.NumberofPointsEditField.Value;

            ev = app.event;

            parfor i = 1:its

                if typ == "RWD"

                    carsweep = carFs;

                else

                    carsweep = carFsAWD;


                end

                if opt1 == "CLA" || opt1 == "CDA"
                    s = size(carsweep.(opt1),3);
                    t = size(carsweep.(opt1),2);
                    carsweep.(opt1)(end+1,:,:)  =vals1(i).*ones(1,t,s);
                elseif opt1 == "trackF" || opt1 == "trackR"
                    carsweep.(opt1) = vals1(i);
                    carsweep.track=(carsweep.trackF + carsweep.trackR) / 2;
                else
                    carsweep.(opt1) = vals1(i);
                end

                %  if isequal(opt1,'Wheel Radius')
                %
                %     carsweep.wheel_rad = vals1(i);
                %
                % elseif isequal(opt1,'Mass')
                %
                %     carsweep.mass = vals1(i);
                %
                %
                % elseif isequal(opt1,'COG Height')
                %
                %     carsweep.cog_height = vals1(i);
                %
                %
                % elseif isequal(opt1,'COG Split')
                %
                %     carsweep.cog_split = vals1(i);
                %
                %
                % elseif isequal(opt1,'COG LR Split')
                %
                %     carsweep.cog_LR_split = vals1(i);
                %
                %
                % elseif isequal(opt1,'COP Height')
                %
                %     carsweep.cop_height = vals1(i);
                %
                %
                % elseif isequal(opt1,'COP Split')
                %
                %    carsweep.cop_split = vals1(i);
                %
                %
                % elseif isequal(opt1,'COP LR Split')
                %
                %     carsweep.cop_LR_split = vals1(i);
                %
                %
                % elseif isequal(opt1,'Wheelbase')
                %
                %     carsweep.wheelbase = vals1(i);
                %
                %
                % elseif isequal(opt1,'TrackF')
                %
                %     carsweep.trackF = vals1(i);
                %
                %
                % elseif isequal(opt1,'TrackR')
                %
                %    carsweep.trackR = vals1(i);
                %
                %
                % elseif isequal(opt1,'CLA')
                %     s = size(carsweep.CLA,3);
                %     t = size(carsweep.CLA,2);
                %     carsweep.CLA(end+1,:,:)  =vals1(i).*ones(1,t,s);
                %
                %
                % elseif isequal(opt1,'CDA')
                %
                %     s = size(carsweep.CDA,3);
                %     t = size(carsweep.CDA,2);
                %     carsweep.CDA(end+1,:,:)  =vals1(i).*ones(1,t,s);
                %
                % elseif isequal(opt1,'Toe')
                %
                %     carsweep.toe = vals1(i);
                %
                % elseif isequal(opt1,'Camber')
                %
                %     carsweep.IA = vals1(i);
                %
                % else
                %
                %     continue
                %
                % end


                for k = 1:its
                    if opt2 == "CLA" || opt2 == "CDA"
                        s = size(carsweep.(opt2),3);
                        t = size(carsweep.(opt2),2);
                        carsweep.(opt2)(end+1,:,:)  =vals2(k).*ones(1,t,s);
                    elseif opt2 == "trackF" || opt2 == "trackR"
                        carsweep.(opt2) = vals2(k);
                        carsweep.track=(carsweep.trackF + carsweep.trackR) / 2;
                    else
                        carsweep.(opt2) = vals2(k);
                    end

                    % if isequal(opt2,'Wheel Radius')
                    %
                    %     carsweep.wheel_rad = vals2(k);
                    %
                    % elseif isequal(opt2,'Mass')
                    %
                    %     carsweep.mass = vals2(k);
                    %
                    % elseif isequal(opt2,'COG Height')
                    %
                    %     carsweep.cog_height = vals2(k);
                    %
                    % elseif isequal(opt2,'COG Split')
                    %
                    %     carsweep.cog_split = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'COG LR Split')
                    %
                    %     carsweep.cog_LR_split = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'COP Height')
                    %
                    %     carsweep.cop_height = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'COP Split')
                    %
                    %    carsweep.cop_split = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'COP LR Split')
                    %
                    %     carsweep.cop_LR_split = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'Wheelbase')
                    %
                    %     carsweep.wheelbase = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'TrackF')
                    %
                    %     carsweep.trackF = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'TrackR')
                    %
                    %    carsweep.trackR = vals2(k);
                    %
                    %
                    % elseif isequal(opt2,'CLA')
                    %
                    %     s = size(carsweep.CLA,3);
                    %     t = size(carsweep.CLA,2);
                    %     carsweep.CLA(end+1,:,:)  =vals2(k).*ones(1,t,s);
                    %
                    %
                    % elseif isequal(opt2,'CDA')
                    %
                    %    s = size(carsweep.CDA,3);
                    %     t = size(carsweep.CDA,2);
                    %     carsweep.CDA(end+1,:,:)  =vals2(k).*ones(1,t,s);
                    % elseif isequal(opt2,'Toe')
                    %
                    %     carsweep.toe = vals2(k);
                    %
                    % elseif isequal(opt2,'Camber')
                    %
                    %     carsweep.IA = vals2(k);
                    %
                    % else
                    %
                    %     continue
                    %
                    % end

                    tempsim = runsim(carsweep,nop,0,ev);

                    time(i,k) = tempsim.time(nop);

                end

            end

            close(d)
            close(fig)

            [x,y] = meshgrid(vals2,vals1);

            surf(app.UIAxes4,x,y,time);

            xlabel(app.UIAxes4, 'Variable 2')
            ylabel(app.UIAxes4, 'Variable 1')
            zlabel(app.UIAxes4, 'Time (s)')


        end

        % Value changed function: TypeofCarDropDown
        function TypeofCarDropDownValueChanged(app, event)
            value = app.TypeofCarDropDown.Value;
            
            if isequal(value,"RWD")

                app.car = carFs;

            else

                app.car = carFsAWD;

            end
            data = readCSV();
            tab = app.UITable.Data;
            for indx=1:height(data)
                rowName = data.Var1{indx};
                if rowName == "CLA" || rowName == "CDA"
                    s = size(app.car.(rowName),3);
                    t = size(app.car.(rowName),2);
                    app.car.(rowName)(end+1,:,:)  = tab.Var2(indx) *ones(1,t,s);
                elseif rowName == "trackF" || rowName == "trackR"
                    app.car.(rowName) = tab.Var2(indx);
                    app.car.track=(app.car.trackF + app.car.trackR) / 2;
                else
                    app.car.(rowName) = tab.Var2(indx);
                end
            end
        end

        % Callback function
        function ProcessButtonPushed(app, event)
            %%%% POST PROCESSING GUI FUNCTIONALITY%%%%%%
            functocall = app.DatatodisplayDropDown.Value;
            if isnan(app.sim.v(end))
                app.sim.v(end) = [];
            end
            if isnan(app.sim.slog.Motor_Fx(1,2,1))
                app.sim.slog.Motor_Fx(1,2,1) = 0;
            end
            if isnan(app.sim.slog.Motor_Fx(1,2,2))
                app.sim.slog.Motor_Fx(1,2,2) = 0;
            end
            if functocall == "Battery Model"


                Batt_names = {};
                Batt_values = [];
                Batt_names(1) = cellstr("SOC Before Lap (%)");
                Batt_values(1) = app.sim.plog.SOC(1)*100;
                Batt_names(2) = cellstr("SOC After Lap (%)");
                Batt_values(2) = app.sim.plog.SOC(end) * 100;
                Batt_names(3) = cellstr("Battery size (Ah)");
                Batt_values(3) = app.sim.plog.Q_max;
                Batt_names = transpose(Batt_names);
                Batt_values = transpose(Batt_values);
                Batttabledata = table(Batt_names, Batt_values);               
                app.UITable3.Data = Batttabledata;
                cla(app.UIAxes5);
                app.UIAxes5.Title.String = 'Battery Model';
                app.UIAxes5.XLabel.String = 'Time (s)';
                app.UIAxes5.YLabel.String = 'SOC';
                plot(app.UIAxes5, app.sim.time, app.sim.plog.SOC);
            end


        end

        % Value changed function: DatatodisplayDropDown
        function DatatodisplayDropDownValueChanged(app, event)
            value = app.DatatodisplayDropDown.Value;

            if value == "Battery Model"
                Batt_names = {};
                Batt_values = [];
                Batt_names(1) = cellstr("SOC Before Lap (%)");
                Batt_values(1) = app.sim.plog.SOC(1)*100;
                Batt_names(2) = cellstr("SOC After Lap (%)");
                Batt_values(2) = app.sim.plog.SOC(end) * 100;
                Batt_names(3) = cellstr("Battery size (Ah)");
                Batt_values(3) = app.sim.plog.Q_max;
                Batt_names = transpose(Batt_names);
                Batt_values = transpose(Batt_values);
                Batttabledata = table(Batt_names, Batt_values);               
                app.UITable3.Data = Batttabledata;
                cla(app.UIAxes5);
                app.UIAxes5.Title.String = 'Battery Model';
                app.UIAxes5.XLabel.String = 'Time (s)';
                app.UIAxes5.YLabel.String = 'SOC';
                plot(app.UIAxes5, app.sim.time, app.sim.plog.SOC);
            end
            if value == "Cooling Model"
                CM_names = {};
                CM_values = [];
                CM_names(1) = cellstr("Peak Temperature (Celsius)");
                CM_values(1) = max(app.sim.plog.T);
                CM_names(2) = cellstr("Average Temperature (Celsius");
                CM_values(2) = (sum(app.sim.plog.T)/length(app.sim.plog.T));
                CM_names = transpose(CM_names);
                CM_values = transpose(CM_values);
                CMtabledata = table(CM_names, CM_values);               
                app.UITable3.Data = CMtabledata;
                cla(app.UIAxes5);
                app.UIAxes5.Title.String = 'Cooling Model';
                app.UIAxes5.XLabel.String = 'Time (s)';
                app.UIAxes5.YLabel.String = 'T (Celsius)';
                plot(app.UIAxes5, app.sim.time, app.sim.plog.T);
            end
        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            value = app.EventDropDown.Value;
            
            if isequal(value,'Sprint')

                app.event = 1;

            elseif isequal(value,'Acceleration')

                app.event = 2;

            elseif isequal(value,'Skidpad')

               app.event = 3;

            end

        end

        % Value changed function: Variable1DropDown
        function Variable1DropDownValueChanged(app, event)
            value = app.Variable1DropDown.Value;
            
            if value=="CLA"||value=="CDA"
                app.ValueEditField.Value = app.car.(value)(end,end,end);
            else


                app.ValueEditField.Value = app.car.(value);      
            end
            app.UpperLimitEditField_2.Value = 1.25*app.ValueEditField.Value;

                app.LowerLimitEditField_2.Value = 0.75*app.ValueEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IFRLapTimeSimulatorUIFigure = uifigure('Visible', 'off');
            app.IFRLapTimeSimulatorUIFigure.Name = 'IFR Lap Time Simulator';
            app.IFRLapTimeSimulatorUIFigure.Icon = fullfile(pathToMLAPP, 'GUI data', 'LionBadgeGrey.png');
            app.TabGroup = uitabgroup(app.IFRLapTimeSimulatorUIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.UIAxes = uiaxes(app.OverviewTab);
            title(app.UIAxes, 'Velocity Vizualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.OverviewTab);
            app.UITable.ColumnName = {'Car Properties'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NumberofPointsEditFieldLabel = uilabel(app.OverviewTab);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.OverviewTab, 'numeric');
            app.NumberofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsEditFieldValueChanged, true);
            app.NumberofPointsEditField.Value = 2000;
            app.ImperialFormulaRacingLabel = uilabel(app.OverviewTab);
            app.ImperialFormulaRacingLabel.Text = 'Imperial Formula Racing';
            app.LapTimeSimulatorSetupLabel = uilabel(app.OverviewTab);
            app.LapTimeSimulatorSetupLabel.Text = 'Lap Time Simulator - Setup';
            app.TypeofCarDropDownLabel = uilabel(app.OverviewTab);
            app.TypeofCarDropDownLabel.Text = 'Type of Car';
            app.TypeofCarDropDown = uidropdown(app.OverviewTab);
            app.TypeofCarDropDown.Items = {'RWD', 'AWD'};
            app.TypeofCarDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofCarDropDownValueChanged, true);
            app.TypeofCarDropDown.Value = 'RWD';
            app.InitiateSimButton = uibutton(app.OverviewTab, 'push');
            app.InitiateSimButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateSimButtonPushed, true);
            app.InitiateSimButton.Text = 'Initiate Sim';
            app.ExportDataButton = uibutton(app.OverviewTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.RestoreDefaultsButton = uibutton(app.OverviewTab, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.UITable2 = uitable(app.OverviewTab);
            app.UITable2.ColumnName = {'Key Data'; 'Value'};
            app.UITable2.RowName = {};
            app.Image = uiimage(app.OverviewTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI data', 'Logo_no_background.png');
            app.EventDropDownLabel = uilabel(app.OverviewTab);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.OverviewTab);
            app.EventDropDown.Items = {'Sprint', 'Acceleration', 'Skidpad'};
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.EventDropDown.Value = 'Skidpad';
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes3 = uiaxes(app.VisualisationTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SelectVariableDropDown_2Label = uilabel(app.VisualisationTab);
            app.SelectVariableDropDown_2Label.Text = 'Select Variable';
            app.SelectVariableDropDown_2 = uidropdown(app.VisualisationTab);
            app.SelectVariableDropDown_2.Items = {'Ax', 'Ay', 'Fz', 'Fy', 'Fx', 'Tire Fx', 'Motor Fx', 'Lift', 'Drag', 'U', 'Slip Ratio', 'Slip Angle', 'Steering Angle', 'Chassis Angle'};
            app.SelectVariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectVariableDropDown_2ValueChanged, true);
            app.SelectVariableDropDown_2.Value = 'Ax';
            app.SelectOptionDropDownLabel = uilabel(app.VisualisationTab);
            app.SelectOptionDropDownLabel.Text = 'Select Option';
            app.SelectOptionDropDown = uidropdown(app.VisualisationTab);
            app.SelectOptionDropDown.Items = {'Whole Car'};
            app.SelectOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectOptionDropDownValueChanged, true);
            app.SelectOptionDropDown.Value = 'Whole Car';
            app.Label_2 = uilabel(app.VisualisationTab);
            app.Label_2.Text = 'Please run the simulation, before plotting otherwise it would throw an error.';
            app.Image_2 = uiimage(app.VisualisationTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'GUI data', 'Logo_no_background.png');
            app.Sweep1DTab = uitab(app.TabGroup);
            app.Sweep1DTab.Title = 'Sweep 1D';
            app.UIAxes2 = uiaxes(app.Sweep1DTab);
            title(app.UIAxes2, 'Sweep Plot')
            xlabel(app.UIAxes2, 'Parameter')
            ylabel(app.UIAxes2, 'Time (s)')
            zlabel(app.UIAxes2, 'Z')
            app.SelectVariableDropDownLabel = uilabel(app.Sweep1DTab);
            app.SelectVariableDropDownLabel.Text = 'Select Variable';
            app.SelectVariableDropDown = uidropdown(app.Sweep1DTab);
            app.SelectVariableDropDown.Items = {'Wheel Radius', 'Tyre Pressure', 'Mass', 'COG Height', 'COG Split', 'COG LR Split', 'COP Height', 'COP Split', 'COP LR Split', 'Wheelbase', 'TrackF', 'TrackR', 'CLA', 'CDA', 'Motor Ratio', 'Toe'};
            app.SelectVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVariableDropDownValueChanged, true);
            app.SelectVariableDropDown.Value = 'Wheel Radius';
            app.LowerLimitEditFieldLabel = uilabel(app.Sweep1DTab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.Sweep1DTab, 'numeric');
            app.UpperLimitEditFieldLabel = uilabel(app.Sweep1DTab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.Sweep1DTab, 'numeric');
            app.RunSweepButton = uibutton(app.Sweep1DTab, 'push');
            app.RunSweepButton.ButtonPushedFcn = createCallbackFcn(app, @RunSweepButtonPushed, true);
            app.RunSweepButton.Text = 'Run Sweep';
            app.NumberofPointsEditField_2Label = uilabel(app.Sweep1DTab);
            app.NumberofPointsEditField_2Label.Text = 'Number of Points';
            app.NumberofPointsEditField_2 = uieditfield(app.Sweep1DTab, 'numeric');
            app.CurrentValueEditFieldLabel = uilabel(app.Sweep1DTab);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.CurrentValueEditField = uieditfield(app.Sweep1DTab, 'numeric');
            app.Image_3 = uiimage(app.Sweep1DTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'GUI data', 'Logo_no_background.png');
            app.Sweep2DTab = uitab(app.TabGroup);
            app.Sweep2DTab.Title = 'Sweep 2D';
            app.UIAxes4 = uiaxes(app.Sweep2DTab);
            title(app.UIAxes4, '2D Sweep')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Variable1DropDownLabel = uilabel(app.Sweep2DTab);
            app.Variable1DropDownLabel.Text = 'Variable 1';
            app.Variable1DropDown = uidropdown(app.Sweep2DTab);
            app.Variable1DropDown.Items = {'Wheel Radius', 'Mass', 'COG Height', 'COG Split', 'COG LR Split', 'COP Height', 'COP Split', 'COP LR Split', 'Wheelbase', 'TrackF', 'TrackR', 'CLA', 'CDA', 'Toe', 'Camber'};
            app.Variable1DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable1DropDownValueChanged, true);
            app.Variable1DropDown.Value = 'Wheel Radius';
            app.Variable2DropDownLabel = uilabel(app.Sweep2DTab);
            app.Variable2DropDownLabel.Text = 'Variable 2';
            app.Variable2DropDown = uidropdown(app.Sweep2DTab);
            app.Variable2DropDown.Items = {'Wheel Radius', 'Mass', 'COG Height', 'COG Split', 'COG LR Split', 'COP Height', 'COP Split', 'COP LR Split', 'Wheelbase', 'TrackF', 'TrackR', 'CLA', 'CDA', 'Toe', 'Camber'};
            app.Variable2DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable2DropDownValueChanged, true);
            app.Variable2DropDown.Value = 'Wheel Radius';
            app.ValueEditFieldLabel = uilabel(app.Sweep2DTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.Sweep2DTab, 'numeric');
            app.UpperLimitEditField_2Label = uilabel(app.Sweep2DTab);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.Sweep2DTab, 'numeric');
            app.LowerLimitEditField_2 = uieditfield(app.Sweep2DTab, 'numeric');
            app.LowerLimitEditField_2Label = uilabel(app.Sweep2DTab);
            app.LowerLimitEditField_2Label.Text = 'Lower Limit';
            app.ValueEditField_2Label = uilabel(app.Sweep2DTab);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.Sweep2DTab, 'numeric');
            app.UpperLimitEditField_3 = uieditfield(app.Sweep2DTab, 'numeric');
            app.UpperLimitEditField_3Label = uilabel(app.Sweep2DTab);
            app.UpperLimitEditField_3Label.Text = 'Upper Limit';
            app.LowerLimitEditField_3 = uieditfield(app.Sweep2DTab, 'numeric');
            app.LowerLimitEditField_3Label = uilabel(app.Sweep2DTab);
            app.LowerLimitEditField_3Label.Text = 'Lower Limit';
            app.NumberofPointsEditField_3Label = uilabel(app.Sweep2DTab);
            app.NumberofPointsEditField_3Label.Text = 'Number of Points';
            app.NumberofPointsEditField_3 = uieditfield(app.Sweep2DTab, 'numeric');
            app.Run2DSweepButton = uibutton(app.Sweep2DTab, 'push');
            app.Run2DSweepButton.ButtonPushedFcn = createCallbackFcn(app, @Run2DSweepButtonPushed, true);
            app.Run2DSweepButton.Text = 'Run 2D Sweep';
            app.Image_4 = uiimage(app.Sweep2DTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'GUI data', 'Logo_no_background.png');
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.UIAxes5 = uiaxes(app.PostProcessingTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.DatatodisplayDropDownLabel = uilabel(app.PostProcessingTab);
            app.DatatodisplayDropDownLabel.Text = 'Data to display';
            app.DatatodisplayDropDown = uidropdown(app.PostProcessingTab);
            app.DatatodisplayDropDown.Items = {'GCI', 'Powertrain', 'Ride Model', 'Cooling Model', 'Battery Model'};
            app.DatatodisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatodisplayDropDownValueChanged, true);
            app.DatatodisplayDropDown.Value = 'GCI';
            app.Label_3 = uilabel(app.PostProcessingTab);
            app.Label_3.Text = 'Please run the simulation before using, otherwise error will be shown.';
            app.UITable3 = uitable(app.PostProcessingTab);
            app.UITable3.ColumnName = {'Property'; 'Value'};
            app.UITable3.RowName = {};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutLabel = uilabel(app.AboutTab);
            app.AboutLabel.Text = 'About';
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = 'The GUI was developed by Charalampos Charalampous. For any queries, cc2621@ic.ac.uk.';
            % Show the figure after all components are created
            app.IFRLapTimeSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IFRLapTimeSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IFRLapTimeSimulatorUIFigure)
        end
    end
end